classdef VISLA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LinearCombinationButton  matlab.ui.control.Button
        pWin                     matlab.ui.control.UIAxes
        InputVectorsPanel        matlab.ui.container.Panel
        scalV                    matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        vLabel                   matlab.ui.control.Label
        wLabel                   matlab.ui.control.Label
        v1                       matlab.ui.control.NumericEditField
        v2                       matlab.ui.control.NumericEditField
        v3                       matlab.ui.control.NumericEditField
        scalW                    matlab.ui.control.NumericEditField
        w1                       matlab.ui.control.NumericEditField
        w2                       matlab.ui.control.NumericEditField
        w3                       matlab.ui.control.NumericEditField
        xLabel                   matlab.ui.control.Label
        yLabel                   matlab.ui.control.Label
        zLabel                   matlab.ui.control.Label
        uLabel                   matlab.ui.control.Label
        scalU                    matlab.ui.control.NumericEditField
        u1                       matlab.ui.control.NumericEditField
        u2                       matlab.ui.control.NumericEditField
        u3                       matlab.ui.control.NumericEditField
        xLabel_3                 matlab.ui.control.Label
        zLabel_3                 matlab.ui.control.Label
        yLabel_3                 matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        TogglePanel              matlab.ui.container.Panel
        ThirdDimensionCheckBox   matlab.ui.control.CheckBox
        ScalarsCheckBox          matlab.ui.control.CheckBox
        AnimateCheckBox          matlab.ui.control.CheckBox
        CenterOriginButton       matlab.ui.control.Button
        OutcomePanel             matlab.ui.container.Panel
        OLabel                   matlab.ui.control.Label
        xLabel_2                 matlab.ui.control.Label
        yLabel_2                 matlab.ui.control.Label
        zLabel_2                 matlab.ui.control.Label
        outLabelX                matlab.ui.control.Label
        outLabelY                matlab.ui.control.Label
        outLabelZ                matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        LinesOriginButton        matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: LinearCombinationButton
        function lComb(app, event)
%             hold(app.pWin,'off') % So it resets the plot
            cla(app.pWin)
            
            scalSet  = app.ScalarsCheckBox.Value;
            aniSet   = app.AnimateCheckBox.Value;
            thirdSet = app.ThirdDimensionCheckBox.Value;
        % ########################
        % ### Plotting of V ######
        % ########################
            if ~thirdSet
                vecV = [app.v1.Value, app.v2.Value];
                orig = [0 0];
            else
                vecV = [app.v1.Value, app.v2.Value, app.v3.Value];
                orig = [0 0 0];
            end
            if app.scalV.Value ~= 0 && scalSet
                vecV = vecV * app.scalV.Value;
            end
        % ########################
        % ### Plotting of W ######
        % ########################
            hold(app.pWin,'on')
            if ~thirdSet
                vecW = [app.w1.Value, app.w2.Value];
            else
                vecW = [app.w1.Value, app.w2.Value, app.w3.Value];
            end
            if app.scalW.Value ~= 0 && scalSet
                vecW = vecW * app.scalW.Value;
            end
            
        % ########################
        % ### Plotting of U ######
        % ########################    
            if thirdSet
                vecU = [app.u1.Value, app.u2.Value, app.u3.Value];
            end
            if app.scalU ~= 0 && scalSet
                vecU = vecU * app.scalW.Value;
            end
        
        % ########################
        % # Plotting of Outcome ##
        % ########################
            vecO = vecV + vecW;

            % Make sure that you are always one away from max and minimum
            minLim = min([orig vecV vecW vecO]) - 1;
            maxLim = max([orig vecV vecW vecO]) + 1;
            xlim(app.pWin,[minLim maxLim])
            ylim(app.pWin,[minLim maxLim])
            if thirdSet
                zlim(app.pWin,[minLim maxLim])
                view(app.pWin, 3)
            else
                view(app.pWin, 2)
            end
            grid(app.pWin, 'on')
            
            aniLength = 25;
                if ~thirdSet % Two dimensions
                    if aniSet
                        lineV = quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','r');
                        title(app.pWin, 'Plotting vector V')
                        pause(1)
                        
                        lineW = quiver(app.pWin, orig(1),orig(2),vecW(1),vecW(2),0,'LineWidth',2,'Color','g');
                        title(app.pWin, 'Plotting vector W')
                        pause(1)
                        
                        title(app.pWin, 'Adding them together')
                        stepX = linspace(orig(1), vecV(1), aniLength);
                        stepY = linspace(orig(2), vecV(2), aniLength);
                        for iAni = 1:aniLength
                            delete(lineW);
                            lineW = quiver(app.pWin, orig(1)+stepX(iAni),orig(2)+stepY(iAni),vecW(1),vecW(2),0,'LineWidth',2,'Color','g');
                            drawnow
                        end
                        delete(lineW)
                        delete(lineV)
                        quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','r','LineStyle',':')
                        quiver(app.pWin, vecV(1),vecV(2),vecW(1),vecW(2),0,'LineWidth',2,'Color','g','LineStyle',':')
                        
                        title(app.pWin, 'Plotting the outcome vector')
                        vecO_ani = [linspace(0,vecO(1),aniLength);linspace(0,vecO(2),aniLength)];
                        for iOut = 1:aniLength
                            lineO = plot(app.pWin, [orig(1), vecO_ani(1,iOut)],[orig(2),vecO_ani(2,iOut)],'LineWidth',2,'Color','b');
                            drawnow
                            delete(lineO)
                        end
                    end
                    
                    quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','r','LineStyle',':')
                    quiver(app.pWin, vecV(1),vecV(2),vecW(1),vecW(2),0,'LineWidth',2,'Color','g','LineStyle',':')
                    quiver(app.pWin, orig(1),orig(2),vecO(1),vecO(2),0,'LineWidth',2,'Color','b')
                    
                else % Three dimensions
                    
                    if aniSet
                        lineV = quiver3(app.pWin, orig(1),orig(2),orig(3),vecV(1),vecV(2),vecV(3),0,'LineWidth',2,'Color','r');
                        title(app.pWin, 'Plotting vector V')
                        pause(1)
                        
                        lineW = quiver3(app.pWin, orig(1),orig(2),orig(3),vecW(1),vecW(2),vecW(3),0,'LineWidth',2,'Color','g');
                        title(app.pWin, 'Plotting vector W')
                        pause(1)
                        
                        lineU = quiver3(app.pWin, orig(1),orig(2),orig(3),vecU(1),vecU(2),vecU(3),0,'LineWidth',2,'Color', [0.30,0.75,0.93]);
                        title(app.pWin, 'Plotting vector U')
                        pause(1)
                        
                        title(app.pWin, 'Adding vector W to vector V')
                        stepX = linspace(orig(1), vecV(1), aniLength);
                        stepY = linspace(orig(2), vecV(2), aniLength);
                        stepZ = linspace(orig(3), vecV(3), aniLength);
                        for iAni = 1:aniLength
                            delete(lineW);
                            lineW = quiver3(app.pWin, orig(1)+stepX(iAni),orig(2)+stepY(iAni),orig(3)+stepZ(iAni),vecW(1),vecW(2),vecW(3),0,'LineWidth',2,'Color','g');
                            drawnow
                        end
                        
                        title(app.pWin, 'Adding vector U to vector V')
                        for iAni = 1:aniLength
                            delete(lineU);
                            lineU = quiver3(app.pWin, orig(1)+stepX(iAni),orig(2)+stepY(iAni),orig(3)+stepZ(iAni),vecU(1),vecU(2),vecU(3),0,'LineWidth',2,'Color', [0.30,0.75,0.93]);
                            drawnow
                        end
                        
                        title(app.pWin, 'Adding vector U to vector W')
                        stepXa = linspace(orig(1), vecU(1), aniLength);
                        stepYa = linspace(orig(2), vecU(2), aniLength);
                        stepZa = linspace(orig(3), vecU(3), aniLength);
                        for iAni = 1:aniLength
                            delete(lineU);
%                             lineU = quiver3(app.pWin, orig(1)+stepXa(iAni),orig(2)+stepYa(iAni),orig(3)+stepZa(iAni),vecU(1),vecU(2),vecU(3),0,'LineWidth',2,'Color', [0.30,0.75,0.93]);
                            lineU = quiver3(app.pWin, orig(1),orig(2),orig(3),vecU(1)+stepXa(iAni),vecU(2)+stepYa(iAni),vecU(3)+stepZa(iAni),0,'LineWidth',2,'Color', [0.30,0.75,0.93]);
                            drawnow
                        end
                        
                        
                        delete(lineW)
                        delete(lineV)
                        quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','r','LineStyle',':')
                        quiver(app.pWin, vecV(1),vecV(2),vecW(1),vecW(2),0,'LineWidth',2,'Color','g','LineStyle',':')
                        
                        title(app.pWin, 'Plotting the outcome vector')
                        vecO_ani = [linspace(0,vecO(1),aniLength);linspace(0,vecO(2),aniLength)];
                        for iOut = 1:aniLength
                            lineO = plot(app.pWin, [orig(1), vecO_ani(1,iOut)],[orig(2),vecO_ani(2,iOut)],'LineWidth',2,'Color','b');
                            drawnow
                            delete(lineO)
                        end
                    end
                    
                    quiver(app.pWin, orig(1),orig(2),vecV(1),vecV(2),0,'LineWidth',2,'Color','r','LineStyle',':')
                    quiver(app.pWin, vecV(1),vecV(2),vecW(1),vecW(2),0,'LineWidth',2,'Color','g','LineStyle',':')
                    quiver(app.pWin, orig(1),orig(2),vecO(1),vecO(2),0,'LineWidth',2,'Color','b')
                    
                    % ##### Animate #####        
% %                     if aniSet
% %                         vecV_ani = [linspace(0,vecV(1),aniLength);linspace(0,vecV(2),aniLength);linspace(0,vecV(3),aniLength)];
% %                         vecW_ani = [linspace(0,vecW(1),aniLength);linspace(0,vecW(2),aniLength);linspace(0,vecW(3),aniLength)];
% %                         vecO_ani = [linspace(0,vecO(1),aniLength);linspace(0,vecO(2),aniLength);linspace(0,vecO(3),aniLength)];
% %                         for iAni = 1:aniLength
% %                             plot3(app.pWin, [orig(1), vecV_ani(1,iAni)],[orig(2),vecV_ani(2,iAni)],[orig(3),vecV_ani(3,iAni)],'LineWidth',2,'Color','r')
% %                             plot3(app.pWin, [orig(1), vecW_ani(1,iAni)],[orig(2),vecW_ani(2,iAni)],[orig(3),vecW_ani(3,iAni)],'LineWidth',2,'Color','g')
% %                             plot3(app.pWin, [orig(1), vecO_ani(1,iAni)],[orig(2),vecO_ani(2,iAni)],[orig(3),vecO_ani(3,iAni)],'LineWidth',2,'Color','b')
% %                             drawnow
% %                         end
% %                     end
% %                     
% %                     quiver3(app.pWin, orig(1),orig(2),orig(3),vecV(1),vecV(2),vecV(3),0,'LineWidth',2,'Color','r')
% %                     quiver3(app.pWin, orig(1),orig(2),orig(3),vecW(1),vecW(2),vecW(3),0,'LineWidth',2,'Color','g')
% %                     quiver3(app.pWin, orig(1),orig(2),orig(3),vecO(1),vecO(2),vecO(3),0,'LineWidth',2,'Color','b')
% %                     app.outLabelZ.Text = num2str(vecO(3));
% %                     app.outLabelZ.Visible = 'on';
                end
        % ########################
        % ## Write the outcome ###
        % ########################
        app.outLabelX.Text = num2str(vecO(1));
        app.outLabelY.Text = num2str(vecO(2));
        app.outLabelX.Visible = 'on';
        app.outLabelY.Visible = 'on';
        
        end

        % Value changed function: ThirdDimensionCheckBox
        function add3D2(app, event)
            value = app.ThirdDimensionCheckBox.Value;
            if value == 1
                app.v3.Enable = 'on';
                app.w3.Enable = 'on';
                app.zLabel.FontColor = 'k';
                app.zLabel_2.FontColor = 'k';
                
                % Third Vector
                app.xLabel_3.FontColor = 'k';
                app.yLabel_3.FontColor = 'k';
                app.zLabel_3.FontColor = 'k';
                app.uLabel.FontColor = [0 0 1];
                app.Label_3.FontColor = 'k';
                app.u1.Enable = 'on';
                app.u2.Enable = 'on';
                app.u3.Enable = 'on';
                app.scalU.Enable = 'on';
            else
                app.v3.Enable = 'off';
                app.w3.Enable = 'off';
                app.zLabel.FontColor = [0.65,0.65,0.65];
                app.zLabel_2.FontColor = [0.65,0.65,0.65];
                
                % Third Vector
                app.xLabel_3.FontColor = [0.65,0.65,0.65];
                app.yLabel_3.FontColor = [0.65,0.65,0.65];
                app.zLabel_3.FontColor = [0.65,0.65,0.65];
                app.uLabel.FontColor = [0.30,0.75,0.93];
                app.Label_3.FontColor = [0.65,0.65,0.65];
                app.u1.Enable = 'off';
                app.u2.Enable = 'off';
                app.u3.Enable = 'off';
                app.scalU.Enable = 'off';
            end
        end

        % Value changed function: ScalarsCheckBox
        function toggleScalars(app, event)
            value = app.ScalarsCheckBox.Value;
            if value == 1
                app.scalV.Enable = 'on';
                app.scalW.Enable = 'on';
            else
                app.scalV.Enable = 'off';
                app.scalW.Enable = 'off';
            end
        end

        % Button pushed function: CenterOriginButton
        function centerZero2(app, event)
%             value = app.CenterCheckBox.Value;
            checkX = xlim(app.pWin);
            checkY = ylim(app.pWin);
            if app.ThirdDimensionCheckBox.Value
                checkZ = zlim(app.pWin);
                zlim(app.pWin,[0 checkZ(2)])
                view(app.pWin, -37.5, 30)
            end
            
            xlim(app.pWin,[0 checkX(2)])
            ylim(app.pWin,[0 checkY(2)])
            
            
        end

        % Button pushed function: LinesOriginButton
        function linesOrigin(app, event)
            plot(app.pWin, [-100 100], [0 0], 'LineWidth',1, 'Color','k')
            plot(app.pWin, [0 0], [-100 100], 'LineWidth',1, 'Color','k')
            plot3(app.pWin, [0 0], [0 0], [-100 100], 'LineWidth',1, 'Color','k')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LinearCombinationButton = uibutton(app.UIFigure, 'push');
            app.LinearCombinationButton.ButtonPushedFcn = createCallbackFcn(app, @lComb, true);
            app.LinearCombinationButton.Text = 'Linear Combination';
            app.pWin = uiaxes(app.UIFigure);
            title(app.pWin, '')
            xlabel(app.pWin, '')
            ylabel(app.pWin, '')
            app.InputVectorsPanel = uipanel(app.UIFigure);
            app.InputVectorsPanel.Title = 'Input Vectors';
            app.scalV = uieditfield(app.InputVectorsPanel, 'numeric');
            app.scalV.Limits = [-9 9];
            app.scalV.Enable = 'off';
            app.Label = uilabel(app.InputVectorsPanel);
            app.Label.Text = '+';
            app.vLabel = uilabel(app.InputVectorsPanel);
            app.vLabel.Text = 'v';
            app.wLabel = uilabel(app.InputVectorsPanel);
            app.wLabel.Text = 'w';
            app.v1 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v1.Limits = [-9 9];
            app.v2 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v2.Limits = [-9 9];
            app.v3 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.v3.Limits = [-9 9];
            app.v3.Enable = 'off';
            app.scalW = uieditfield(app.InputVectorsPanel, 'numeric');
            app.scalW.Limits = [-9 9];
            app.scalW.Enable = 'off';
            app.w1 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w1.Limits = [-9 9];
            app.w2 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w2.Limits = [-9 9];
            app.w3 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.w3.Limits = [-9 9];
            app.w3.Enable = 'off';
            app.xLabel = uilabel(app.InputVectorsPanel);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.InputVectorsPanel);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.InputVectorsPanel);
            app.zLabel.Text = 'z';
            app.uLabel = uilabel(app.InputVectorsPanel);
            app.uLabel.Text = 'u';
            app.scalU = uieditfield(app.InputVectorsPanel, 'numeric');
            app.scalU.Limits = [-9 9];
            app.scalU.Enable = 'off';
            app.u1 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.u1.Limits = [-9 9];
            app.u1.Enable = 'off';
            app.u2 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.u2.Limits = [-9 9];
            app.u2.Enable = 'off';
            app.u3 = uieditfield(app.InputVectorsPanel, 'numeric');
            app.u3.Limits = [-9 9];
            app.u3.Enable = 'off';
            app.xLabel_3 = uilabel(app.InputVectorsPanel);
            app.xLabel_3.Text = 'x';
            app.zLabel_3 = uilabel(app.InputVectorsPanel);
            app.zLabel_3.Text = 'z';
            app.yLabel_3 = uilabel(app.InputVectorsPanel);
            app.yLabel_3.Text = 'y';
            app.Label_3 = uilabel(app.InputVectorsPanel);
            app.Label_3.Text = '+';
            app.TogglePanel = uipanel(app.UIFigure);
            app.TogglePanel.Title = 'Toggle';
            app.ThirdDimensionCheckBox = uicheckbox(app.TogglePanel);
            app.ThirdDimensionCheckBox.ValueChangedFcn = createCallbackFcn(app, @add3D2, true);
            app.ThirdDimensionCheckBox.Text = 'Third Dimension';
            app.ScalarsCheckBox = uicheckbox(app.TogglePanel);
            app.ScalarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @toggleScalars, true);
            app.ScalarsCheckBox.Text = 'Scalars';
            app.AnimateCheckBox = uicheckbox(app.TogglePanel);
            app.AnimateCheckBox.Text = 'Animate';
            app.CenterOriginButton = uibutton(app.TogglePanel, 'push');
            app.CenterOriginButton.ButtonPushedFcn = createCallbackFcn(app, @centerZero2, true);
            app.CenterOriginButton.Text = 'Center Origin';
            app.OutcomePanel = uipanel(app.UIFigure);
            app.OutcomePanel.Title = 'Outcome';
            app.OLabel = uilabel(app.OutcomePanel);
            app.OLabel.Text = 'O';
            app.xLabel_2 = uilabel(app.OutcomePanel);
            app.xLabel_2.Text = 'x';
            app.yLabel_2 = uilabel(app.OutcomePanel);
            app.yLabel_2.Text = 'y';
            app.zLabel_2 = uilabel(app.OutcomePanel);
            app.zLabel_2.Text = 'z';
            app.outLabelX = uilabel(app.OutcomePanel);
            app.outLabelX.Visible = 'off';
            app.outLabelX.Text = 'x';
            app.outLabelY = uilabel(app.OutcomePanel);
            app.outLabelY.Visible = 'off';
            app.outLabelY.Text = 'y';
            app.outLabelZ = uilabel(app.OutcomePanel);
            app.outLabelZ.Visible = 'off';
            app.outLabelZ.Text = 'z';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.LinesOriginButton = uibutton(app.UIFigure, 'push');
            app.LinesOriginButton.ButtonPushedFcn = createCallbackFcn(app, @linesOrigin, true);
            app.LinesOriginButton.Text = 'Lines  Origin';
        end
    end

    methods (Access = public)

        % Construct app
        function app = VISLA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end